classdef Start_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Tab4_EnableCheckBox           matlab.ui.control.CheckBox
        Tab3_EnableCheckBox           matlab.ui.control.CheckBox
        Tab2_EnableCheckBox           matlab.ui.control.CheckBox
        Tab1_EnableCheckBox           matlab.ui.control.CheckBox
        TabGroup                      matlab.ui.container.TabGroup
        Step1Tab                      matlab.ui.container.Tab
        DiscriptionLabel              matlab.ui.control.Label
        ImageNameTextArea             matlab.ui.control.TextArea
        ImageNameTextAreaLabel        matlab.ui.control.Label
        SelectDatasethereButton       matlab.ui.control.Button
        Tree                          matlab.ui.container.Tree
        NextStepButton                matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        Step2Tab                      matlab.ui.container.Tab
        UITable_3                     matlab.ui.control.Table
        UITable_2                     matlab.ui.control.Table
        SelectarectangletoconfirmButton  matlab.ui.control.StateButton
        ClickthepointtoconfirmButton  matlab.ui.control.StateButton
        UITable                       matlab.ui.control.Table
        NextStepButton_2              matlab.ui.control.Button
        LampForRW                     matlab.ui.control.Lamp
        LampForVP                     matlab.ui.control.Lamp
        SelectrearwallButton          matlab.ui.control.Button
        SelectVanishingpointButton    matlab.ui.control.Button
        UIAxes_2                      matlab.ui.control.UIAxes
        Step3Tab                      matlab.ui.container.Tab
        NextStepButton_3              matlab.ui.control.Button
        LampForFG                     matlab.ui.control.Lamp
        SelectForegroundButton        matlab.ui.control.Button
        ResetButton_2                 matlab.ui.control.Button
        UIAxes_3                      matlab.ui.control.UIAxes
        TourTab                       matlab.ui.container.Tab
        XSlider                       matlab.ui.control.Slider
        XSliderLabel                  matlab.ui.control.Label
        YSlider                       matlab.ui.control.Slider
        YSliderLabel                  matlab.ui.control.Label
        UITableCam                    matlab.ui.control.Table
        ResetButton_3                 matlab.ui.control.Button
        phiSlider                     matlab.ui.control.Slider
        phiSliderLabel                matlab.ui.control.Label
        GoButton                      matlab.ui.control.Button
        thetaSlider                   matlab.ui.control.Slider
        thetaSliderLabel              matlab.ui.control.Label
        ZSlider                       matlab.ui.control.Slider
        ZSliderLabel                  matlab.ui.control.Label
        movedLabel                    matlab.ui.control.Label
        originalLabel                 matlab.ui.control.Label
        UITableOriginal3              matlab.ui.control.Table
        UITableOriginal2              matlab.ui.control.Table
        UITableNormal                 matlab.ui.control.Table
        UITableHomo                   matlab.ui.control.Table
        UIAxes_4                      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        DEBUG_MODE = true;                          % if true, run debug mode
        default_image_names = {'metro-station', 'oil-painting', ...
            'sagrada_familia', 'shopping-mall', 'simple-room', 'uhren-turm'};
        image_path                                  % Path of images
        image_names                                 % The name list of the select dataset

        selected_image                              % Path of the selected image
        selected_image_name                         % The name of the selected image
        selected_image_data                         % The data of selected image (unit8)
        
        x_limit;                                    % The pixel limit in horizontal direction
        y_limit;                                    % The pixel limit in vertical direction

        select_point;                               % Selected vanishing point by user. (Data type: Point)
        select_rectange;                            % Selected rectangle by user. (Data type: rectangle)
        
        draw_point;                                 % The drawed point on the UIAxes_2, can be deleted by delete(.).
        draw_rectangle;                             % The drawed rectangle on the UIAxes_2, can be deleted by delete(.);
        
        
        expandImage;                                % Selected image with a black border, 
                                                    % in order to make sure all coordinates are positive.
        
        Vertex;                                     % The coordinates of 12 vertices
        VanishingPoint_Original;
        VanishingPoint;                             % The coordinate of selected vanishing point (with border)
        RW7;
        RW8;
        RW1;
        RW2;
        RW_xmin;
        RW_ymin;
        RW_width;
        RW_height;
        
        FW_raw;

       
        RealWall;                                   % Splited trapezoid of real wall
        LeftWall;                                   % Splited trapezoid of left wall
        RightWall;                                  % Splited trapezoid of right wall
        Ceil;                                       % Splited trapezoid of ceiling
        Floor;                                      % Splited trapezoid of floor

        movedRealWall;                              % Fixed rectangle of real wall        
        movedLeftWall;                              % Fixed rectangle of left wall
        movedRightWall;                             % Fixed rectangle of right wall
        movedCeil;                                  % Fixed rectangle of ceiling
        movedFloor;                                 % Fixed rectangle of floor

        checkVP = false;                            % Check whether the vanishing point is successfully inputed.
        checkRW = false;                            % Check whether the rear wall is successfully inputed.
        checkFG = false;

        rx
        ry
        irx
        iry
        vxnew
        vynew

    end
    
    methods (Access = private)

    end
   
    
    methods (Access = public)
        
        function GetFixedSplitedRect(app)
                % reset all points (border changed)
                app.RW7 = [app.RW_xmin, app.RW_ymin];
                app.RW8 = [app.RW_xmin+app.RW_width, app.RW_ymin];
                app.RW1 = [app.RW_xmin, app.RW_ymin+app.RW_height];
                app.RW2 = [app.RW_xmin+app.RW_width, app.RW_ymin+app.RW_height];
                app.RealWall = round([app.RW7; app.RW8; app.RW1; app.RW2]);
                app.VanishingPoint = app.VanishingPoint_Original;

                GetFixedSplitedRectFunc(app);
%                 projection3d(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath('functions'))
            % lock the interaction with difference highlight
            app.UIAxes.Interactions = [];

            % to make sure that button only visible after selecting image
            app.NextStepButton.Visible = "off";
            app.Step2Tab.HandleVisibility = "off";
        end

        % Button pushed function: SelectDatasethereButton
        function SelectDatasethereButtonPushed(app, event)
            GetDatasetList(app)
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            if ~isempty(selectedNodes.NodeData)
                % save the selected dataset path
                app.selected_image = selectedNodes.NodeData;
                app.selected_image_data = imread(selectedNodes.NodeData);
                % get the dataset 
                % get the dataset name
                [~,dirname,~] = fileparts(selectedNodes.NodeData);
                app.selected_image_name = dirname;
                app.ImageNameTextArea.Value = app.selected_image_name;
                % read the number of pixels of selected picture.
                [app.y_limit,app.x_limit,~] = size(app.selected_image_data);

                % show the selected image on axes.
                imshow(app.selected_image, 'Parent', app.UIAxes);hold(app.UIAxes, 'on' );
                imshow(app.selected_image, 'Parent', app.UIAxes_2);hold(app.UIAxes_2, 'on' );
                imshow(app.selected_image, 'Parent', app.UIAxes_3);
                
                % set the maximum value of x-axe and y-axe. 
                app.UIAxes.YLim = [0 app.y_limit];
                app.UIAxes.XLim = [0 app.x_limit];

                app.UIAxes_2.YLim = [0 app.y_limit];
                app.UIAxes_2.XLim = [0 app.x_limit];

                app.UIAxes_3.YLim = [0 app.y_limit];
                app.UIAxes_3.XLim = [0 app.x_limit];

                % show the next setp button
                app.NextStepButton.Visible = "on";
                app.Step2Tab.HandleVisibility = "on";
                
            else
                
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            % Look up the value of the paired checkbox
            % Each "case" is the title of a Tab and contains the pair checkbox.
            switch selectedTab.Title
                case 'Step 1'
                    checkboxValue = app.Tab1_EnableCheckBox.Value;
                case 'Step 2'
                    checkboxValue = app.Tab2_EnableCheckBox.Value;
                case 'Step3'
                    checkboxValue = app.Tab3_EnableCheckBox.Value;
                case 'Tour'
                    checkboxValue = app.Tab4_EnableCheckBox.Value;
                otherwise
                    error('Unknown tab: %s.',selectedTab.Title)
            end

            % If paired checkbox is checked, return to the previously selected tab
            if ~checkboxValue
                app.TabGroup.SelectedTab = event.OldValue;
            end
        end

        % Button pushed function: SelectVanishingpointButton
        function SelectVanishingpointButtonPushed(app, event)
            app.checkVP = false;
            app.LampForVP.Color = 'r';
            app.NextStepButton_2.Visible = 'off';        
            app.NextStepButton_2.Enable = 'off';    
            if app.checkVP ~= app.checkRW
%                 app.select_rectange.Visible = 'off';
                imshow(app.selected_image, 'Parent', app.UIAxes_2);
                hold(app.UIAxes_2, 'on' );
                app.draw_rectangle = plot(app.irx,app.iry,'b','Parent', app.UIAxes_2); 
            end

            app.SelectVanishingpointButton.Visible = 'off';
            app.SelectVanishingpointButton.Enable = 'off';
            
            app.ClickthepointtoconfirmButton.Visible = 'on';
            app.ClickthepointtoconfirmButton.Enable = 'on';
            
            delete(app.select_point);
            delete(app.draw_point);
            app.select_point = drawpoint(app.UIAxes_2);
            app.select_point.Visible = 'off';
            % get the coordinate of selected point.
            app.VanishingPoint_Original = round(app.select_point.Position);
            app.draw_point = plot(app.UIAxes_2, app.VanishingPoint_Original(1),app.VanishingPoint_Original(2), ...
                'Marker','o','MarkerSize',8,'MarkerFaceColor','m'); 
%             app.UITable.Data = array2table(app.VanishingPoint);
            app.VanishingPoint = app.VanishingPoint_Original;

            app.ClickthepointtoconfirmButton.Visible = 'off';
            app.ClickthepointtoconfirmButton.Enable = 'off';
            app.SelectVanishingpointButton.Visible = 'on';
            app.SelectVanishingpointButton.Enable = 'on';
            app.LampForVP.Color = 'g';

            % set the sign
            app.checkVP = true;

            % check if the other button has been selected 
            if app.checkVP == app.checkRW
                GetFixedSplitedRect(app);
            end
        end

        % Button pushed function: SelectrearwallButton
        function SelectrearwallButtonPushed(app, event)
            app.NextStepButton_2.Visible = 'off';        
            app.NextStepButton_2.Enable = 'off';  
            app.checkRW = false;
            app.LampForRW.Color = 'r';
            if app.checkVP ~= app.checkRW
%                 app.select_point.Visible = 'off';
                imshow(app.selected_image, 'Parent', app.UIAxes_2);
                hold(app.UIAxes_2, 'on' );
                app.draw_point = plot(app.UIAxes_2, app.VanishingPoint(1),app.VanishingPoint(2), ...
                    'Marker','o','MarkerSize',8,'MarkerFaceColor','m'); 
            end

            app.SelectrearwallButton.Visible = 'off';
            app.SelectrearwallButton.Enable = 'off';
            app.SelectarectangletoconfirmButton.Visible = 'on';
            app.SelectarectangletoconfirmButton.Enable = 'on';
            
            delete(app.draw_rectangle);
            delete(app.select_rectange);
            app.select_rectange = drawrectangle(app.UIAxes_2);
            app.select_rectange.Visible = 'off';
            
            positionRW = app.select_rectange.Position;
            app.RW_xmin = positionRW(1);
            app.RW_ymin = positionRW(2);
            app.RW_width = positionRW(3);
            app.RW_height = positionRW(4);
            
            app.RW7 = [app.RW_xmin, app.RW_ymin];
            app.RW8 = [app.RW_xmin+app.RW_width, app.RW_ymin];
            app.RW1 = [app.RW_xmin, app.RW_ymin+app.RW_height];
            app.RW2 = [app.RW_xmin+app.RW_width, app.RW_ymin+app.RW_height];

            % update the value to the global params
            app.rx = [app.RW_xmin, app.RW_xmin+app.RW_width];
            app.ry = [app.RW_ymin, app.RW_ymin+app.RW_height];
            app.irx = round([app.rx(1) app.rx(2) app.rx(2) app.rx(1) app.rx(1)]);
            app.iry = round([app.ry(1) app.ry(1) app.ry(2) app.ry(2) app.ry(1)]);
            app.draw_rectangle = plot(app.irx,app.iry,'b','Parent', app.UIAxes_2); 
            % 
            app.RealWall = round([app.RW7; app.RW8; app.RW1; app.RW2]);
            
            app.SelectrearwallButton.Visible = 'on';
            app.SelectrearwallButton.Enable = 'on';
            app.SelectarectangletoconfirmButton.Visible = 'off';
            app.SelectarectangletoconfirmButton.Enable = 'off';
            app.LampForRW.Color = 'g';  

            % set the sign
            app.checkRW = true;

            % check if the other button has been selected 
            if app.checkVP == app.checkRW
                GetFixedSplitedRect(app);
            end
        end

        % Button pushed function: NextStepButton
        function NextStepButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Step2Tab;
            app.Tab2_EnableCheckBox.Value = true;
        end

        % Button pushed function: NextStepButton_2
        function NextStepButton_2Pushed(app, event)
            app.TabGroup.SelectedTab = app.Step3Tab;
        end

        % Button pushed function: NextStepButton_3
        function NextStepButton_3Pushed(app, event)
            app.TabGroup.SelectedTab = app.TourTab;
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            vp = app.VanishingPoint;
            p1 = app.Vertex(1,:);
            p2 = app.Vertex(2,:);
            p3 = app.Vertex(3,:);
            p4 = app.Vertex(4,:);
            p5 = app.Vertex(5,:);
            p6 = app.Vertex(6,:);
            p7 = app.Vertex(7,:);
            p8 = app.Vertex(8,:);
            p9 = app.Vertex(9,:);
            p10 = app.Vertex(10,:);
            p11 = app.Vertex(11,:);
            p12 = app.Vertex(12,:);

            width = app.x_limit;
            height = app.y_limit; 

            [vp_3d, p7_3d, p8_3d, p1_3d, p2_3d, p11_3d,...
                 p10_3d, p4_3d, p5_3d, p9_3d, p3_3d, p12_3d, p6_3d, eyep] = get3D(...
                vp, p7, p8, p1, p2, p11, p10, p4, p5, p9, p3, p12, p6,height, width);
    
           points_3d = round([vp_3d;
                              p1_3d;
                              p2_3d; 
                              p3_3d;
                              p4_3d;
                              p5_3d;
                              p6_3d;
                              p7_3d; 
                              p8_3d;
                              p9_3d; 
                              p10_3d; 
                              p11_3d; 
                              p12_3d]);
    
          app.UITableOriginal2.Data = array2table([app.VanishingPoint; app.Vertex]);
          app.UITableOriginal3.Data = array2table(points_3d);

          x = app.XSlider.Value;
          y = app.YSlider.Value;
          z = app.ZSlider.Value;
          theta = app.thetaSlider.Value;
          phi = app.phiSlider.Value;

            [transVP_3, transP1_3, transP2_3, transP3_3, transP4_3, transP5_3,...
             transP6_3, transP7_3, transP8_3, transP9_3, transP10_3, transP11_3,...
             transP12_3, transVP_2, transP1_2, transP2_2, transP3_2, transP4_2, transP5_2,...
             transP6_2, transP7_2, transP8_2, transP9_2, transP10_2, transP11_2,...
             transP12_2, cam] =  getTransCoordinates2D(vp, p7, p8, p1, p2, p11, p10, ...
                                        p4, p5, p9, p3, p12, p6,height, width, theta, phi, x, y, z);
            homo = round([transVP_3;
                transP1_3;
                transP2_3;
                transP3_3;
                transP4_3;
                transP5_3;
                transP6_3;
                transP7_3;
                transP8_3;
                transP9_3;
                transP10_3;
                transP11_3;
                transP12_3]);
            normal = round([transVP_2;
                transP1_2;
                transP2_2;
                transP3_2;
                transP4_2;
                transP5_2;
                transP6_2;
                transP7_2;
                transP8_2;
                transP9_2;
                transP10_2;
                transP11_2;
                transP12_2]);

            app.UITableHomo.Data = array2table(homo);
            app.UITableNormal.Data = array2table(normal);
            app.UITableCam.Data = array2table(cam);

            app.movedRealWall = getNewTrapezoid (app.RealWall, p7, p8, p2, p1, transP7_2, transP8_2, transP2_2, transP1_2);
            app.movedCeil = getNewTrapezoid (app.Ceil, p9, p10, p8, p7, transP9_2, transP10_2, transP8_2, transP7_2);
            app.movedFloor = getNewTrapezoid (app.Floor, p1, p2, p4, p3, transP1_2, transP2_2, transP4_2, transP3_2);
            app.movedRightWall = getNewTrapezoid (app.RightWall, p8, p12, p6, p2, transP8_2, transP12_2, transP6_2, transP2_2);
            app.movedLeftWall = getNewTrapezoid (app.LeftWall, p11, p7, p1, p5, transP11_2, transP7_2, transP1_2, transP5_2);

            newImage = app.movedRealWall+app.movedCeil+app.movedFloor+app.movedRightWall+app.movedLeftWall;
            imshow(newImage, 'Parent', app.UIAxes_4);
        

        end

        % Button pushed function: ResetButton_3
        function ResetButton_3Pushed(app, event)
            app.ZSlider.Value = 0;
            app.thetaSlider.Value = 0;
            app.phiSlider.Value = 0;
            app.XSlider.Value = 0;
            app.YSlider.Value = 0;

        end

        % Value changing function: ZSlider
        function ZSliderValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changed function: ZSlider
        function ZSliderValueChanged(app, event)
            value = app.ZSlider.Value;
            
        end

        % Button pushed function: SelectForegroundButton
        function SelectForegroundButtonPushed(app, event)

            
            if app.checkFG == true
                imshow(app.selected_image, 'Parent', app.UIAxes_3);
                app.LampForFG.Color = 'r';
            end


            %% first method: draw rectangle 
%             FG_rect = drawrectangle(app.UIAxes_3);
% 
%             roiPosition = FG_rect.Position;
%             xmin = roiPosition(1);
%             ymin = roiPosition(2);
%             width = roiPosition(3);
%             height = roiPosition(4);
% 
%             app.FW_raw = [xmin, ymin;
%                          xmin+width, ymin;
%                          xmin+width, ymin+height;
%                          xmin, ymin+height];


            %% second method: draw polygon (FG_raw also return 4 vertices)
            FG_rect = drawpolygon(app.UIAxes_3);

            app.FW_raw = FG_rect.Position;

            roi = poly2mask(app.FW_raw(:,1),app.FW_raw(:,2),app.y_limit,app.x_limit);
            L = superpixels(app.selected_image_data,500);

            BW = grabcut(app.selected_image_data, L, roi);

            BW3(:,:,1) = BW;
            BW3(:,:,2) = BW;
            BW3(:,:,3) = BW;

            img = im2double(app.selected_image_data);
            imshow(double(BW3).*img, 'Parent', app.UIAxes_3);

            xmin = min(app.FW_raw(:,1));
            xmax = max(app.FW_raw(:,1));
            ymin = min(app.FW_raw(:,2));
            ymax = max(app.FW_raw(:,2));

            app.FW_raw = [];
            app.FW_raw = [xmin, ymin;
                          xmax, ymin;
                          xmax, ymax;
                          xmin, ymax];
            %

            app.LampForFG.Color = 'g';
            app.checkFG = true;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.Step1Tab = uitab(app.TabGroup);
            app.Step1Tab.Title = 'Step 1';
            app.UIAxes = uiaxes(app.Step1Tab);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Tag = 'axes1';
            app.NextStepButton = uibutton(app.Step1Tab, 'push');
            app.NextStepButton.ButtonPushedFcn = createCallbackFcn(app, @NextStepButtonPushed, true);
            app.NextStepButton.Text = 'Next Step';
            app.Tree = uitree(app.Step1Tab);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.SelectDatasethereButton = uibutton(app.Step1Tab, 'push');
            app.SelectDatasethereButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDatasethereButtonPushed, true);
            app.SelectDatasethereButton.Text = 'Select Dataset here';
            app.ImageNameTextAreaLabel = uilabel(app.Step1Tab);
            app.ImageNameTextAreaLabel.Text = 'Image Name: ';
            app.ImageNameTextArea = uitextarea(app.Step1Tab);
            app.ImageNameTextArea.Editable = 'off';
            app.DiscriptionLabel = uilabel(app.Step1Tab);
            app.DiscriptionLabel.Text = 'Discription:';
            app.Step2Tab = uitab(app.TabGroup);
            app.Step2Tab.Title = 'Step 2';
            app.UIAxes_2 = uiaxes(app.Step2Tab);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.Tag = 'axes1';
            app.SelectVanishingpointButton = uibutton(app.Step2Tab, 'push');
            app.SelectVanishingpointButton.ButtonPushedFcn = createCallbackFcn(app, @SelectVanishingpointButtonPushed, true);
            app.SelectVanishingpointButton.Text = 'Select Vanishing point';
            app.SelectrearwallButton = uibutton(app.Step2Tab, 'push');
            app.SelectrearwallButton.ButtonPushedFcn = createCallbackFcn(app, @SelectrearwallButtonPushed, true);
            app.SelectrearwallButton.Text = 'Select rear wall';
            app.LampForVP = uilamp(app.Step2Tab);
            app.LampForRW = uilamp(app.Step2Tab);
            app.NextStepButton_2 = uibutton(app.Step2Tab, 'push');
            app.NextStepButton_2.ButtonPushedFcn = createCallbackFcn(app, @NextStepButton_2Pushed, true);
            app.NextStepButton_2.Visible = 'off';
            app.NextStepButton_2.Text = 'Next Step';
            app.UITable = uitable(app.Step2Tab);
            app.UITable.ColumnName = {'X'; 'Y'};
            app.UITable.RowName = {'VP'; 'RW'};
            app.ClickthepointtoconfirmButton = uibutton(app.Step2Tab, 'state');
            app.ClickthepointtoconfirmButton.Enable = 'off';
            app.ClickthepointtoconfirmButton.Visible = 'off';
            app.ClickthepointtoconfirmButton.Text = 'Click the point to confirm';
            app.SelectarectangletoconfirmButton = uibutton(app.Step2Tab, 'state');
            app.SelectarectangletoconfirmButton.Enable = 'off';
            app.SelectarectangletoconfirmButton.Visible = 'off';
            app.SelectarectangletoconfirmButton.Text = 'Select a rectangle to confirm';
            app.UITable_2 = uitable(app.Step2Tab);
            app.UITable_2.ColumnName = '';
            app.UITable_2.RowName = {};
            app.UITable_3 = uitable(app.Step2Tab);
            app.UITable_3.ColumnName = '';
            app.UITable_3.RowName = {};
            app.Step3Tab = uitab(app.TabGroup);
            app.Step3Tab.Title = 'Step3';
            app.UIAxes_3 = uiaxes(app.Step3Tab);
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_3.Tag = 'axes1';
            app.ResetButton_2 = uibutton(app.Step3Tab, 'push');
            app.ResetButton_2.Text = 'Reset';
            app.SelectForegroundButton = uibutton(app.Step3Tab, 'push');
            app.SelectForegroundButton.ButtonPushedFcn = createCallbackFcn(app, @SelectForegroundButtonPushed, true);
            app.SelectForegroundButton.Text = 'Select Foreground';
            app.LampForFG = uilamp(app.Step3Tab);
            app.NextStepButton_3 = uibutton(app.Step3Tab, 'push');
            app.NextStepButton_3.ButtonPushedFcn = createCallbackFcn(app, @NextStepButton_3Pushed, true);
            app.NextStepButton_3.Text = 'Next Step';
            app.TourTab = uitab(app.TabGroup);
            app.TourTab.Title = 'Tour';
            app.UIAxes_4 = uiaxes(app.TourTab);
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.UIAxes_4.Tag = 'axes1';
            app.UITableHomo = uitable(app.TourTab);
            app.UITableHomo.ColumnName = {'x '; 'y '; 'homo'};
            app.UITableHomo.RowName = {'vp'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'};
            app.UITableNormal = uitable(app.TourTab);
            app.UITableNormal.ColumnName = {'x'; 'y'};
            app.UITableNormal.RowName = {'vp'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'};
            app.UITableOriginal2 = uitable(app.TourTab);
            app.UITableOriginal2.ColumnName = {'x '; 'y '};
            app.UITableOriginal2.RowName = {'vp'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'};
            app.UITableOriginal3 = uitable(app.TourTab);
            app.UITableOriginal3.ColumnName = {'x '; 'y '; 'homo'};
            app.UITableOriginal3.RowName = {'vp'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'};
            app.originalLabel = uilabel(app.TourTab);
            app.originalLabel.Text = 'original';
            app.movedLabel = uilabel(app.TourTab);
            app.movedLabel.Text = 'moved';
            app.ZSliderLabel = uilabel(app.TourTab);
            app.ZSliderLabel.Text = 'Z';
            app.ZSlider = uislider(app.TourTab);
            app.ZSlider.Limits = [-5 5];
            app.ZSlider.ValueChangedFcn = createCallbackFcn(app, @ZSliderValueChanged, true);
            app.ZSlider.ValueChangingFcn = createCallbackFcn(app, @ZSliderValueChanging, true);
            app.thetaSliderLabel = uilabel(app.TourTab);
            app.thetaSliderLabel.Text = 'theta';
            app.thetaSlider = uislider(app.TourTab);
            app.thetaSlider.Limits = [-0.785398163397448 0.785398163397448];
            app.GoButton = uibutton(app.TourTab, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.phiSliderLabel = uilabel(app.TourTab);
            app.phiSliderLabel.Text = 'phi';
            app.phiSlider = uislider(app.TourTab);
            app.phiSlider.Limits = [-0.785398163397448 0.785398163397448];
            app.ResetButton_3 = uibutton(app.TourTab, 'push');
            app.ResetButton_3.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_3Pushed, true);
            app.ResetButton_3.Text = 'Reset';
            app.UITableCam = uitable(app.TourTab);
            app.UITableCam.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableCam.RowName = {};
            app.YSliderLabel = uilabel(app.TourTab);
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.TourTab);
            app.YSlider.Limits = [-5 5];
            app.XSliderLabel = uilabel(app.TourTab);
            app.XSliderLabel.Text = 'X';
            app.XSlider = uislider(app.TourTab);
            app.XSlider.Limits = [-5 5];
            app.Tab1_EnableCheckBox = uicheckbox(app.UIFigure);
            app.Tab1_EnableCheckBox.Text = 'Tab1_Enable';
            app.Tab1_EnableCheckBox.Value = true;
            app.Tab2_EnableCheckBox = uicheckbox(app.UIFigure);
            app.Tab2_EnableCheckBox.Text = 'Tab2_Enable';
            app.Tab3_EnableCheckBox = uicheckbox(app.UIFigure);
            app.Tab3_EnableCheckBox.Text = 'Tab3_Enable';
            app.Tab4_EnableCheckBox = uicheckbox(app.UIFigure);
            app.Tab4_EnableCheckBox.Text = 'Tab4_Enable';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Start_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end